classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        DataTab                    matlab.ui.container.Tab
        WegdeSubmitButton          matlab.ui.control.Button
        StrategySubmitButton       matlab.ui.control.Button
        ConstraintsSubmitButton    matlab.ui.control.Button
        StrategyEditFieldLabel     matlab.ui.control.Label
        StrategyEditField          matlab.ui.control.EditField
        WedgesEditFieldLabel       matlab.ui.control.Label
        WedgesEditField            matlab.ui.control.EditField
        ConstraintsEditFieldLabel  matlab.ui.control.Label
        ConstraintsEditField       matlab.ui.control.EditField
        UseDefaultButton           matlab.ui.control.Button
        NothingEnteredLampLabel    matlab.ui.control.Label
        StrategyLamp               matlab.ui.control.Lamp
        NothingEnteredLabel        matlab.ui.control.Label
        WedgesLamp                 matlab.ui.control.Lamp
        NothingEnteredLamp_3Label  matlab.ui.control.Label
        ConstraintsLamp            matlab.ui.control.Lamp
        GameTab                    matlab.ui.container.Tab
        UIAxes                     matlab.ui.control.UIAxes
        AvailableStrategiesLabel   matlab.ui.control.Label
        StrategiesListBox          matlab.ui.control.ListBox
        StrategiesInUseListBox     matlab.ui.control.ListBox
        StrategiesinUseLabel       matlab.ui.control.Label
        DescriptionLabel           matlab.ui.control.Label
        DescriptionTextArea        matlab.ui.control.TextArea
        StartYearSliderLabel       matlab.ui.control.Label
        StartYearSlider            matlab.ui.control.Slider
        EndYearSliderLabel         matlab.ui.control.Label
        EndYearSlider              matlab.ui.control.Slider
    end


    properties (Access = private)
        
        wedgesData; %Struct to store Wedges data from text file
        constraintsData; %Struct to store Constraints data from text file
        strategyData; %Struct to store Strategy data from text file
        historicalData; %Struct to store Historical data from text file
    end
    

    methods (Access = private)


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.historicalData = tdfread("historicaldata.txt");
            
            plot(app.UIAxes,app.historicalData.Year,app.historicalData.fossil_fuel_and_industry_GtC0x2Fyr);
            app.StartYearSlider.Value = app.UIAxes.XLim(1);
            app.EndYearSlider.Value = app.UIAxes.XLim(2);
            
        end

        % Button pushed function: StrategySubmitButton
        function StrategySubmitButtonFcn(app, event)

            try 
                %load the file into a global variable as a struct
                strategy_filepath = app.StrategyEditField.Value; 
                app.strategyData = tdfread(strategy_filepath, ' ');
                
                
                %update UI to show loading was a success
                app.StrategyLamp.Color = 'green';
                app.NothingEnteredLampLabel.Text = 'File Loaded';
                
                updateUI(app);

            catch E
                %update UI to show error in loading file
                app.StrategyLamp.Color = 'red';
                app.NothingEnteredLampLabel.Text = 'File not Found';
            end

        end

        % Button pushed function: ConstraintsSubmitButton
        function ConstraintsSubmitButtonFcn(app, event)
            try 
                %load the file into a global variable as a struct
                constraints_filepath = app.ConstraintsEditField.Value;
                app.constraintsData = tdfread(constraints_filepath, ";");
                
                %update UI to show loading was a success
                app.ConstraintsLamp.Color = 'green';
                app.NothingEnteredLamp_3Label.Text = 'File Loaded';
                  
                updateUI(app);


            catch E
                %update UI to show error in loading file
                app.ConstraintsLamp.Color = 'red';
                app.NothingEnteredLamp_3Label.Text = 'File not Found';
            end
        end

        % Button pushed function: WegdeSubmitButton
        function WegdeSubmitButtonFcn(app, event)
            try 
                %load the file into a global variable as a struct
                wedges_filepath = app.WedgesEditField.Value;
                app.wedgesData = readtable(wedges_filepath);   
                
                
                %update UI to show loading was a success
                app.WedgesLamp.Color = 'green';
                app.NothingEnteredLabel.Text = 'File Loaded';
    
                app.StrategiesListBox.Items =app.wedgesData.StrategyName;
    
                
            catch E
                %update UI to show error in loading file
                display(E)
                app.WedgesLamp.Color = 'red';
                app.NothingEnteredLabel.Text = 'File not Found';
            end
        end

        % Value changed function: StrategiesListBox
        function StrategiesListBoxValueChanged(app, event)
            value = app.StrategiesListBox.Value;
            strategy = value{1};
    
            %show description
            index_of_description = find(app.wedgesData.StrategyName == convertCharsToStrings(strategy),1);
            app.DescriptionTextArea.Value = app.wedgesData.Description{index_of_description};


            %check if the strategy can be used
            if ~any(strcmp(value,app.StrategiesInUseListBox.Items))
                
                app.StrategiesInUseListBox.Items{end+1} = strategy;
                
                
            end
           
            
        end

        % Value changed function: StartYearSlider
        function StartYearSliderValueChanged(app, event)
            value = app.StartYearSlider.Value;
            
            %check to see if value is valid and change graph limits
            if value > app.UIAxes.XLim(2)
                app.StartYearSlider.Value = app.UIAxes.XLim(1);
            else
                app.UIAxes.XLim(1) = value;
            end


        end

        % Value changed function: EndYearSlider
        function EndYearSliderValueChanged(app, event)
            value = app.EndYearSlider.Value;
            
            %check to see if value is valid and change graph limits
            if value < app.UIAxes.XLim(1)
                app.StartYearSlider.Value = app.UIAxes.XLim(1);
            else
                app.UIAxes.XLim(2) = value;
            end
             
        end

        % Value changed function: StrategiesInUseListBox
        function StrategiesInUseListBoxValueChanged(app, event)
            value = app.StrategiesInUseListBox.Value;
            
            
            %remove strategy from being used
            index_to_be_removed = find(app.StrategiesInUseListBox.Items == convertCharsToStrings(value),1);
            app.StrategiesInUseListBox.Items(index_to_be_removed) = [];
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.WegdeSubmitButton = uibutton(app.DataTab, 'push');
            app.WegdeSubmitButton.ButtonPushedFcn = createCallbackFcn(app, @WegdeSubmitButtonFcn, true);
            app.WegdeSubmitButton.Text = 'Submit';
            app.StrategySubmitButton = uibutton(app.DataTab, 'push');
            app.StrategySubmitButton.ButtonPushedFcn = createCallbackFcn(app, @StrategySubmitButtonFcn, true);
            app.StrategySubmitButton.Text = 'Submit';
            app.ConstraintsSubmitButton = uibutton(app.DataTab, 'push');
            app.ConstraintsSubmitButton.ButtonPushedFcn = createCallbackFcn(app, @ConstraintsSubmitButtonFcn, true);
            app.ConstraintsSubmitButton.Text = 'Submit';
            app.StrategyEditFieldLabel = uilabel(app.DataTab);
            app.StrategyEditFieldLabel.Text = 'Strategy';
            app.StrategyEditField = uieditfield(app.DataTab, 'text');
            app.StrategyEditField.Value = 'strategies.txt';
            app.WedgesEditFieldLabel = uilabel(app.DataTab);
            app.WedgesEditFieldLabel.Text = 'Wedges';
            app.WedgesEditField = uieditfield(app.DataTab, 'text');
            app.WedgesEditField.Value = 'wedges.txt';
            app.ConstraintsEditFieldLabel = uilabel(app.DataTab);
            app.ConstraintsEditFieldLabel.Text = 'Constraints';
            app.ConstraintsEditField = uieditfield(app.DataTab, 'text');
            app.ConstraintsEditField.Value = 'constraints.txt';
            app.UseDefaultButton = uibutton(app.DataTab, 'push');
            app.UseDefaultButton.Text = 'Use Default';
            app.NothingEnteredLampLabel = uilabel(app.DataTab);
            app.NothingEnteredLampLabel.Text = 'Nothing Entered';
            app.StrategyLamp = uilamp(app.DataTab);
            app.NothingEnteredLabel = uilabel(app.DataTab);
            app.NothingEnteredLabel.Text = 'Nothing Entered';
            app.WedgesLamp = uilamp(app.DataTab);
            app.NothingEnteredLamp_3Label = uilabel(app.DataTab);
            app.NothingEnteredLamp_3Label.Text = 'Nothing Entered';
            app.ConstraintsLamp = uilamp(app.DataTab);
            app.GameTab = uitab(app.TabGroup);
            app.GameTab.Title = 'Game';
            app.UIAxes = uiaxes(app.GameTab);
            title(app.UIAxes, 'Projected Pollution')
            xlabel(app.UIAxes, 'Year')
            ylabel(app.UIAxes, 'Billions of Tons of Carbon per Year')
            app.AvailableStrategiesLabel = uilabel(app.GameTab);
            app.AvailableStrategiesLabel.Text = 'Available Strategies';
            app.StrategiesListBox = uilistbox(app.GameTab);
            app.StrategiesListBox.Items = {};
            app.StrategiesListBox.Multiselect = 'on';
            app.StrategiesListBox.ValueChangedFcn = createCallbackFcn(app, @StrategiesListBoxValueChanged, true);
            app.StrategiesListBox.Value = {};
            app.StrategiesInUseListBox = uilistbox(app.GameTab);
            app.StrategiesInUseListBox.Items = {};
            app.StrategiesInUseListBox.ValueChangedFcn = createCallbackFcn(app, @StrategiesInUseListBoxValueChanged, true);
            app.StrategiesInUseListBox.Value = {};
            app.StrategiesinUseLabel = uilabel(app.GameTab);
            app.StrategiesinUseLabel.Text = 'Strategies in Use';
            app.DescriptionLabel = uilabel(app.GameTab);
            app.DescriptionLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.GameTab);
            app.DescriptionTextArea.Editable = 'off';
            app.StartYearSliderLabel = uilabel(app.GameTab);
            app.StartYearSliderLabel.Text = 'Start Year';
            app.StartYearSlider = uislider(app.GameTab);
            app.StartYearSlider.Limits = [1950 2050];
            app.StartYearSlider.ValueChangedFcn = createCallbackFcn(app, @StartYearSliderValueChanged, true);
            app.StartYearSlider.Value = 1950;
            app.EndYearSliderLabel = uilabel(app.GameTab);
            app.EndYearSliderLabel.Text = 'End Year';
            app.EndYearSlider = uislider(app.GameTab);
            app.EndYearSlider.Limits = [1950 2050];
            app.EndYearSlider.ValueChangedFcn = createCallbackFcn(app, @EndYearSliderValueChanged, true);
            app.EndYearSlider.Value = 1950;
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end